classdef classBrowser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Class_Browser           matlab.ui.Figure
        ClassMembersPanel       matlab.ui.container.Panel
        Panel_2                 matlab.ui.container.Panel
        ClassMemberAxes         matlab.ui.control.UIAxes
        Panel                   matlab.ui.container.Panel
        ClassesListBoxLabel     matlab.ui.control.Label
        ClassesListBox          matlab.ui.control.ListBox
        JumptoCometButton       matlab.ui.control.Button
        RemoveCometsButton      matlab.ui.control.Button
        ReclassifyCometsButton  matlab.ui.control.Button
        Image                   matlab.ui.control.Image
    end

    
    properties (Access = private)
        classCatalog % This contains the images, maps and other infos of comets
        selectedObjects % Container of selected comets
        Imgs_Composed % Image container of with colorized masks
        ImgsNames % original image name list
        CometIcon % Description
        Classes % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Classes, Imgs_Composed, ImgsNames)
            app.Imgs_Composed = Imgs_Composed;
            app.ImgsNames = ImgsNames;
            app.CometIcon = imread('Logo_withCT_BK255.png');
            app.Image.ImageSource = app.CometIcon;
            app.Classes = Classes;
            
            axesWidth = app.ClassMemberAxes.Position(3);
            app.ClassMemberAxes.XTickLabel = [];
            app.ClassMemberAxes.YTickLabel = [];
            app.ClassMemberAxes.XAxis.Visible = 'off';
            app.ClassMemberAxes.YAxis.Visible = 'off';
            app.ClassMemberAxes.Toolbar.Visible = "off";
            disableDefaultInteractivity(app.ClassMemberAxes)
            app.classCatalog = composeClassImage(Classes, axesWidth);
            app.ClassesListBox.Items = app.classCatalog(:,1);
            app.ClassesListBox.Value = app.classCatalog{1,1};
            imshow(app.classCatalog{1,2}(:,:,1),'Parent',app.ClassMemberAxes);
            app.Panel_2.Position(4) = size(app.classCatalog{1,2}(:,:,1),1)+1;
            app.ClassMemberAxes.Position(4) = size(app.classCatalog{1,2}(:,:,1),1);
            app.ClassMemberAxes.XLim = [0 axesWidth];
            
            app.selectedObjects.hRec = [];
            app.selectedObjects.info = [];


        end

        % Value changed function: ClassesListBox
        function ClassesListBoxValueChanged(app, event)
            value = not(cellfun('isempty',strfind(app.ClassesListBox.Items,app.ClassesListBox.Value)));
            cla(app.ClassMemberAxes);
            app.ClassMemberAxes.Position(4) = size(app.classCatalog{value,2}(:,:,1),1);
%             app.Panel_2.Position(4) = max(size(app.classCatalog{value,2}(:,:,1),1), app.ClassMembersPanel.Position(4));
            if size(app.classCatalog{value,2}(:,:,1),1) < app.ClassMembersPanel.Position(4)
                app.ClassMemberAxes.Position(2) = app.ClassMembersPanel.Position(4) - size(app.classCatalog{value,2}(:,:,1),1);
                app.Panel_2.Position(4) = app.ClassMembersPanel.Position(4);
            else
                app.Panel_2.Position(4) = size(app.classCatalog{value,2}(:,:,1),1);
                app.ClassMemberAxes.Position(2) = 5;
            end
            imshow(app.classCatalog{value,2}(:,:,1),'Parent',app.ClassMemberAxes);
        end

        % Window button down function: Class_Browser
        function Class_BrowserWindowButtonDown(app, event)
            cp = app.ClassMemberAxes.CurrentPoint;
            value = not(cellfun('isempty',strfind(app.ClassesListBox.Items,app.ClassesListBox.Value)));
            x = round(cp(1, 1));
            y = round(cp(1, 2));
            catalogImSize = size(app.classCatalog{value,2}(:,:,1),1);
            lw = 4;
            thumbnailSize = 94;
            maskImPadSize = 300;
            
            if x>0 && y>0 && x<app.ClassMemberAxes.Position(3) && y<catalogImSize %%%%%% if hit on axes
                idx = app.classCatalog{value,2}(y,x,2);
                if idx>0  %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% if hit on valid thumbnail
                    [hi, width] = size(app.classCatalog{value,3});
                    [row,col] = ind2sub([width hi],idx);
                    cometInfo = app.classCatalog{value,3}{col,row};
                    [imCoorX, imCoorY] = find(app.classCatalog{value,2}(:,:,2) == idx);
                    
                    drawX = min(imCoorY);
                    drawY = min(imCoorX);
                    
                    if strcmp(app.Class_Browser.SelectionType,'normal') %%%%%%%%%%%%%%%% if normal selection
                        if ~isempty(app.selectedObjects.info)
                            delete(app.selectedObjects.hRec)
                            app.selectedObjects.info = [];
                        end
                        app.selectedObjects.info = cometInfo;
                        app.selectedObjects.hRec = rectangle('Position',[drawX, drawY, thumbnailSize, thumbnailSize],'EdgeColor', 'red', 'LineWidth',lw, 'Parent', app.ClassMemberAxes);
                        IndImgShown = not(cellfun('isempty',strfind(app.ImgsNames,app.selectedObjects.info.ImageName)));
                        coor = app.Classes.(app.ClassesListBox.Value).Members(idx).thumbnailCoor;
                        app.Image.ImageSource = app.Imgs_Composed(:,:,1:3,IndImgShown);
                    else     %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% if multy selection
                        app.Image.ImageSource = app.CometIcon;
                        selected = [app.selectedObjects.info.CellNumber] == idx;
                        if any(selected)
                            app.selectedObjects.info(selected) = [];
                            delete(app.selectedObjects.hRec(selected));
                        end
                        numOfSelected = length(app.selectedObjects.info);
                        app.selectedObjects.info(numOfSelected+1,1) = cometInfo;
                        app.selectedObjects.hRec(numOfSelected+1,1) = rectangle('Position',[drawX, drawY, thumbnailSize, thumbnailSize],'EdgeColor', 'red', 'LineWidth',lw, 'Parent', app.ClassMemberAxes);
                    end
                else
                    delete(app.selectedObjects.hRec)
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Class_Browser = uifigure('Visible', 'off');
            app.Class_Browser.Name = 'MATLAB App';
            app.Class_Browser.WindowButtonDownFcn = createCallbackFcn(app, @Class_BrowserWindowButtonDown, true);
            app.ClassMembersPanel = uipanel(app.Class_Browser);
            app.ClassMembersPanel.Title = 'Class Members';
            app.Panel_2 = uipanel(app.ClassMembersPanel);
            app.ClassMemberAxes = uiaxes(app.Panel_2);
            title(app.ClassMemberAxes, '')
            xlabel(app.ClassMemberAxes, '')
            ylabel(app.ClassMemberAxes, '')
            app.Panel = uipanel(app.Class_Browser);
            app.Panel.Title = 'Panel';
            app.ClassesListBoxLabel = uilabel(app.Panel);
            app.ClassesListBoxLabel.Text = 'Classes';
            app.ClassesListBox = uilistbox(app.Panel);
            app.ClassesListBox.Items = {};
            app.ClassesListBox.ValueChangedFcn = createCallbackFcn(app, @ClassesListBoxValueChanged, true);
            app.ClassesListBox.Value = {};
            app.JumptoCometButton = uibutton(app.Panel, 'push');
            app.JumptoCometButton.Text = 'Jump to Comet';
            app.RemoveCometsButton = uibutton(app.Panel, 'push');
            app.RemoveCometsButton.Text = 'Remove Comets';
            app.ReclassifyCometsButton = uibutton(app.Panel, 'push');
            app.ReclassifyCometsButton.Text = 'Reclassify Comets';
            app.Image = uiimage(app.Panel);
            % Show the figure after all components are created
            app.Class_Browser.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = classBrowser(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Class_Browser)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Class_Browser)
        end
    end
end